classdef MagnetometerCalibrationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        Label                   matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        HardIronButton          matlab.ui.control.Button
        HardIronSoftIronButton  matlab.ui.control.Button
        SoftIronButton          matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
        Tab_2                   matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Property % Description
        
        DataMagnetometer;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if (~exist('DataMagnitometer.mat', 'file')) % проверка на существование файла
                return;
            end
            
            % Загрузка из .mat в uitable
            app.DataMagnetometer = load('DataMagnitometer.mat');
            tableDataMagnetometer = array2table(app.DataMagnetometer.xyz);
            tableDataMagnetometer = table2cell(tableDataMagnetometer);
            app.UITable.Data = tableDataMagnetometer;
            app.UITable.RowName = 'numbered';
            
            % Распределение данных по переменным
            xyzDataMagnetometer = app.DataMagnetometer.xyz;
            x = xyzDataMagnetometer(:,1);
            y = xyzDataMagnetometer(:,2);
            z = xyzDataMagnetometer(:,3);
            
            % Графики
            cla(app.UIAxes);
            plot3(app.UIAxes,x,y,z);
            legend(app.UIAxes,'исходные данные','Location','best');
            xlabel(app.UIAxes,"x");ylabel(app.UIAxes,"y");zlabel(app.UIAxes,"z");
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
        end

        % Button pushed function: HardIronButton
        function HardIronButtonPushed(app, event)
            if isempty(app.DataMagnetometer) % проверка на наличие данных
                return;
            end
            
            % Распределение данных по переменным
            xyzDataMagnetometer = app.DataMagnetometer.xyz;
            x = xyzDataMagnetometer(:,1);
            y = xyzDataMagnetometer(:,2);
            z = xyzDataMagnetometer(:,3);
            
            %% Hard Iron помехи
            x_c = (max(x)+min(x))/2;
            y_c = (max(y)+min(y))/2;
            z_c = (max(z)+min(z))/2;

            x_h = x - x_c;
            y_h = y - y_c;
            z_h = z - z_c;
            
            % Графики
            plot3(app.UIAxes,x_h,y_h,z_h,'Color','r');
            hold(app.UIAxes,"on");
        end

        % Button pushed function: SoftIronButton
        function SoftIronButtonPushed(app, event)
            if isempty(app.DataMagnetometer) % проверка на наличие данных
                return;
            end
            
            % Распределение данных по переменным
            xyzDataMagnetometer = app.DataMagnetometer.xyz;
            x = xyzDataMagnetometer(:,1);
            y = xyzDataMagnetometer(:,2);
            z = xyzDataMagnetometer(:,3);
            
            %% Soft Iron помехи
            ax = max(x)-min(x);
            ay = max(y)-min(y);
            az = max(z)-min(z);
            a = [ax ay az];
            [m,i] = min(a);
            
            kx_s = a(i)/a(1);
            ky_s = a(i)/a(2);
            kz_s = a(i)/a(3);
            
            x_s = kx_s*x;
            y_s = ky_s*y;
            z_s = kz_s*z;
            
            % Графики
            plot3(app.UIAxes,x_s,y_s,z_s,'Color','g');
            hold(app.UIAxes,"on");
        end

        % Button pushed function: HardIronSoftIronButton
        function HardIronSoftIronButtonPushed(app, event)
            if isempty(app.DataMagnetometer) % проверка на наличие данных
                return;
            end
            
            % Распределение данных по переменным
            xyzDataMagnetometer = app.DataMagnetometer.xyz;
            x = xyzDataMagnetometer(:,1);
            y = xyzDataMagnetometer(:,2);
            z = xyzDataMagnetometer(:,3);
            
            %% Hard Iron помехи
            x_c = (max(x)+min(x))/2;
            y_c = (max(y)+min(y))/2;
            z_c = (max(z)+min(z))/2;

            x_h = x - x_c;
            y_h = y - y_c;
            z_h = z - z_c;
            
            %% Soft Iron помехи
            ax = max(x_h)-min(x_h);
            ay = max(y_h)-min(y_h);
            az = max(z_h)-min(z_h);
            a = [ax ay az];
            [m,i] = min(a);
            
            kx_s = a(i)/a(1);
            ky_s = a(i)/a(2);
            kz_s = a(i)/a(3);
            
            x_s = kx_s*x_h;
            y_s = ky_s*y_h;
            z_s = kz_s*z_h;
            
            % Графики
            plot3(app.UIAxes,x_s,y_s,z_s,'Color','b');
            hold(app.UIAxes,"on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Калибровка магнитометра';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {''};
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Показания магнитометра';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'График-траектория данных')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HardIronButton = uibutton(app.Tab, 'push');
            app.HardIronButton.ButtonPushedFcn = createCallbackFcn(app, @HardIronButtonPushed, true);
            app.HardIronButton.Text = 'Hard Iron';
            app.HardIronSoftIronButton = uibutton(app.Tab, 'push');
            app.HardIronSoftIronButton.ButtonPushedFcn = createCallbackFcn(app, @HardIronSoftIronButtonPushed, true);
            app.HardIronSoftIronButton.Text = 'Hard Iron + Soft Iron';
            app.SoftIronButton = uibutton(app.Tab, 'push');
            app.SoftIronButton.ButtonPushedFcn = createCallbackFcn(app, @SoftIronButtonPushed, true);
            app.SoftIronButton.Text = 'Soft Iron';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = 'Калибровка магнитометра';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Новое';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagnetometerCalibrationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end